classdef pregunta1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ExamendepracticaPanel   matlab.ui.container.Panel
        Pregunta1ButtonGroup    matlab.ui.container.ButtonGroup
        CirculoButton           matlab.ui.control.RadioButton
        PentagonoButton         matlab.ui.control.RadioButton
        IsocosaedroButton       matlab.ui.control.RadioButton
        cuadradode5ladosButton  matlab.ui.control.RadioButton
        SalirButton             matlab.ui.control.Button
        Image                   matlab.ui.control.Image
        TextArea                matlab.ui.control.TextArea
        SiguienteButton         matlab.ui.control.Button
        Siguiente2Button        matlab.ui.control.Button
        ComenzarButton          matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function correctas = (0);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SalirButton
        function SalirButtonPushed(app, event)
            exit
        end

        % Button pushed function: SiguienteButton
        function SiguienteButtonPushed(app, event)
            app.TextArea.Value = "Hola bb esta es ahora la pregunta 2"
            app.Siguiente2Button.Visible = 'on';
            app.SiguienteButton.Visible = 'off';
            if(app.PentagonoButton == 1);
                correctas + 1;
            else
                correctas + 0;
            end
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            
        end

        % Button pushed function: Siguiente2Button
        function Siguiente2ButtonPushed(app, event)
            app.TextArea = correctas;
        end

        % Button pushed function: ComenzarButton
        function ComenzarButtonPushed(app, event)
            app.Pregunta1ButtonGroup.Visible = 'on';
            app.ComenzarButton.Visible = 'off';
        end

        % Size changed function: ExamendepracticaPanel
        function ExamendepracticaPanelSizeChanged(app, event)
            position = app.ExamendepracticaPanel.Position;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ExamendepracticaPanel = uipanel(app.UIFigure);
            app.ExamendepracticaPanel.Title = 'Examen de practica';
            app.ExamendepracticaPanel.SizeChangedFcn = createCallbackFcn(app, @ExamendepracticaPanelSizeChanged, true);
            app.Pregunta1ButtonGroup = uibuttongroup(app.ExamendepracticaPanel);
            app.Pregunta1ButtonGroup.Title = 'Pregunta 1';
            app.Pregunta1ButtonGroup.Visible = 'off';
            app.CirculoButton = uiradiobutton(app.Pregunta1ButtonGroup);
            app.CirculoButton.Text = 'Circulo';
            app.CirculoButton.Value = true;
            app.PentagonoButton = uiradiobutton(app.Pregunta1ButtonGroup);
            app.PentagonoButton.Text = 'Pentagono';
            app.IsocosaedroButton = uiradiobutton(app.Pregunta1ButtonGroup);
            app.IsocosaedroButton.Text = 'Isocosaedro';
            app.cuadradode5ladosButton = uiradiobutton(app.Pregunta1ButtonGroup);
            app.cuadradode5ladosButton.Text = 'cuadrado de 5 lados';
            app.SalirButton = uibutton(app.Pregunta1ButtonGroup, 'push');
            app.SalirButton.ButtonPushedFcn = createCallbackFcn(app, @SalirButtonPushed, true);
            app.SalirButton.Text = 'Salir';
            app.Image = uiimage(app.Pregunta1ButtonGroup);
            app.Image.ImageSource = 'pentagon.png';
            app.TextArea = uitextarea(app.Pregunta1ButtonGroup);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.TextArea.Value = {'¿Cuál es el nombre de la figura?'};
            app.SiguienteButton = uibutton(app.Pregunta1ButtonGroup, 'push');
            app.SiguienteButton.ButtonPushedFcn = createCallbackFcn(app, @SiguienteButtonPushed, true);
            app.SiguienteButton.Text = 'Siguiente';
            app.Siguiente2Button = uibutton(app.Pregunta1ButtonGroup, 'push');
            app.Siguiente2Button.ButtonPushedFcn = createCallbackFcn(app, @Siguiente2ButtonPushed, true);
            app.Siguiente2Button.Visible = 'off';
            app.Siguiente2Button.Text = 'Siguiente 2';
            app.ComenzarButton = uibutton(app.ExamendepracticaPanel, 'push');
            app.ComenzarButton.ButtonPushedFcn = createCallbackFcn(app, @ComenzarButtonPushed, true);
            app.ComenzarButton.Text = 'Comenzar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pregunta1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end