classdef preguntas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ExamendepracticaPanel    matlab.ui.container.Panel
        MainPanel                matlab.ui.container.ButtonGroup
        Abutton                  matlab.ui.control.RadioButton
        Bbutton                  matlab.ui.control.RadioButton
        Cbutton                  matlab.ui.control.RadioButton
        Dbutton                  matlab.ui.control.RadioButton
        SalirButton              matlab.ui.control.Button
        ImagePregunta2           matlab.ui.control.Image
        TextAreaSmall            matlab.ui.control.TextArea
        Siguiente2Button         matlab.ui.control.Button
        Siguiente3Button         matlab.ui.control.Button
        TextAreaLarge            matlab.ui.control.TextArea
        Siguiente4Button         matlab.ui.control.Button
        CorrectasEditFieldLabel  matlab.ui.control.Label
        CorrectasEditField       matlab.ui.control.NumericEditField
        ComenzarButton           matlab.ui.control.Button
        CalificarButton          matlab.ui.control.Button
    end

preguntas = ["Pregunta 1", "Pregunta 2", "Pregunta 3"]

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SalirButton
        function SalirButtonPushed(app, event)
            open home
        end

        % Button pushed function: Siguiente2Button
        function Siguiente2ButtonPushed(app, event)
            %Escribe la siguiente pregunta VERIFICA SI ES EN TEXTSMALL O
            %LARGE
            app.TextAreaLarge.Value = "El precio de unos pantalones de mezclilla es de $800.00, un dia de ofertas su precio se redujo en 1/4, un cliente compro el mismo pantalon , al pagar en la caja, se aplico un 5% sobre lo ya rebajado,¿Cuál fue el precio final del pantalón?"
            
            %Valores de los incisos para la siguiente pregunta
            app.Abutton.Text = '$450.00';
            app.Bbutton.Text = '$560.00';
            app.Cbutton.Text = '$570.00'; %Correcta
            app.Dbutton.Text = '$690.00'; 
            
            %ocultar y calificar respuesta
            app.Siguiente3Button.Visible = 'on';
            app.Siguiente2Button.Visible = 'off';
            app.MainPanel.Title = 'Pregunta 2';
            app.ImagePregunta2.Visible = 'off'
            app.TextAreaSmall.Visible = 'off'
            app.TextAreaLarge.Visible = 'on'
            
            %Recuerda que no estas respondiendo los incisos de aqui sino
            %los de la pregunta anterior
            
            %Respuesta B Pentagono
            if app.Bbutton.Value == 1
                app.CorrectasEditField.Value = app.CorrectasEditField.Value + 1;
            end
        end

        % Button pushed function: ComenzarButton
        function ComenzarButtonPushed(app, event)
            %Cuando pulsamos el boton comenzar se escriben 
            %la pregunta 1 con sus incisos
            
            app.MainPanel.Visible = 'on';
            app.TextAreaLarge.Visible = 'on';
            app.TextAreaLarge.Value = 'El numero que continua la sucesion 3, 7, 5, 11, 15, _, es:';
            app.Abutton.Text = '18';
            app.Bbutton.Text = '19'; %Correcta
            app.Cbutton.Text = '20';
            app.Dbutton.Text = '21';
            app.ComenzarButton.Visible = 'off';
        end

        % Value changed function: TextAreaSmall
        function TextAreaSmallValueChanged(app, event)
            value = app.TextAreaSmall.Value;
            
        end

        % Button pushed function: Siguiente3Button
        function Siguiente3ButtonPushed(app, event)
            %Escribe la siguiente pregunta VERIFICA SI ES EN TEXTSMALL O
            %LARGE
            
            %Pregunta 3
            app.TextAreaLarge.Value = "Qué hora es, si se sabe que el tiempo transcurrido del día es 2/3 de lo que tarda por transcurrir?"
            
            %Valores de los incisos para la siguiente pregunta
            app.Abutton.Text = '8:30 h';
            app.Bbutton.Text = '9:22 h'; 
            app.Cbutton.Text = '9:36 h'; %Correcta
            app.Dbutton.Text = '10:45 h'; 
            
            %ocultar y calificar respuesta
            app.Siguiente3Button.Visible = 'off';
            app.MainPanel.Title = 'Pregunta 3';
            app.Siguiente4Button.Visible = 'on'
            %app.ImagePregunta2.Visible = 'off'
            app.TextAreaSmall.Visible = 'off'
            app.TextAreaLarge.Visible = 'on'
            
            %Recuerda que no estas respondiendo los incisos de aqui sino
            %los de la pregunta anterior
            
            %Respuesta C 570
            if app.Cbutton.Value == 1
                app.CorrectasEditField.Value = app.CorrectasEditField.Value + 1;
            end
        end

        % Size changed function: ExamendepracticaPanel
        function ExamendepracticaPanelSizeChanged(app, event)
            position = app.ExamendepracticaPanel.Position;
        end

        % Button pushed function: CalificarButton
        function CalificarButtonPushed(app, event)
            if app.Abutton.Value == 1
                app.CorrectasEditField.Value = app.CorrectasEditField.Value + 1;
            end
            
            app.MainPanel.Visible = 'off';
            app.ExamendepracticaPanel.Title = 'Examen finalizado con exito!';
            app.CorrectasEditFieldLabel.Visible = 'on';
            app.CorrectasEditField.Visible = 'on';
        end

        % Image clicked function: ImagePregunta2
        function ImagePregunta2Clicked(app, event)
            
        end

        % Button pushed function: Siguiente4Button
        function Siguiente4ButtonPushed(app, event)
            %Escribe la siguiente pregunta VERIFICA SI ES EN TEXTSMALL O
            %LARGE
            
            %Pregunta 3
            app.TextAreaLarge.Value = "Pregunta 4?"
            
            %Valores de los incisos para la siguiente pregunta
            app.Abutton.Text = 'no';
            app.Bbutton.Text = 'no'; 
            app.Cbutton.Text = 'no'; 
            app.Dbutton.Text = 'si'; %Correcta
            
            %ocultar y calificar respuesta
            %app.Siguiente3Button.Visible = 'off';
            
            app.MainPanel.Title = 'Pregunta 4';
            
            app.Siguiente4Button.Visible = 'off';
            %app.Siguiente5Button.Visible = 'off';
            
            app.TextAreaSmall.Visible = 'off'
            app.TextAreaLarge.Visible = 'on'
            
            %Recuerda que no estas respondiendo los incisos de aqui sino
            %los de la pregunta anterior
            
            %QUITAR ESTE COMENTARIO EN LA ULTIMA PREGUNTA
            app.CalificarButton.Visible = 'on';
            
            %Respuesta C 9,36h
            if app.Cbutton.Value == 1
                app.CorrectasEditField.Value = app.CorrectasEditField.Value + 1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ExamendepracticaPanel = uipanel(app.UIFigure);
            app.ExamendepracticaPanel.Title = 'Examen de practica';
            app.ExamendepracticaPanel.SizeChangedFcn = createCallbackFcn(app, @ExamendepracticaPanelSizeChanged, true);
            app.MainPanel = uibuttongroup(app.ExamendepracticaPanel);
            app.MainPanel.Title = 'Pregunta 1';
            app.Abutton = uiradiobutton(app.MainPanel);
            app.Abutton.Text = '';
            app.Abutton.Value = true;
            app.Bbutton = uiradiobutton(app.MainPanel);
            app.Bbutton.Text = '';
            app.Cbutton = uiradiobutton(app.MainPanel);
            app.Cbutton.Text = '';
            app.Dbutton = uiradiobutton(app.MainPanel);
            app.Dbutton.Text = '';
            app.SalirButton = uibutton(app.MainPanel, 'push');
            app.SalirButton.ButtonPushedFcn = createCallbackFcn(app, @SalirButtonPushed, true);
            app.SalirButton.Text = 'Salir';
            app.ImagePregunta2 = uiimage(app.MainPanel);
            app.ImagePregunta2.ImageClickedFcn = createCallbackFcn(app, @ImagePregunta2Clicked, true);
            app.ImagePregunta2.Visible = 'off';
            app.ImagePregunta2.ImageSource = 'pentagon.png';
            app.TextAreaSmall = uitextarea(app.MainPanel);
            app.TextAreaSmall.ValueChangedFcn = createCallbackFcn(app, @TextAreaSmallValueChanged, true);
            app.TextAreaSmall.Editable = 'off';
            app.Siguiente2Button = uibutton(app.MainPanel, 'push');
            app.Siguiente2Button.ButtonPushedFcn = createCallbackFcn(app, @Siguiente2ButtonPushed, true);
            app.Siguiente2Button.Text = 'Siguiente 2';
            app.Siguiente3Button = uibutton(app.MainPanel, 'push');
            app.Siguiente3Button.ButtonPushedFcn = createCallbackFcn(app, @Siguiente3ButtonPushed, true);
            app.Siguiente3Button.Visible = 'off';
            app.Siguiente3Button.Text = 'Siguiente 3';
            app.TextAreaLarge = uitextarea(app.MainPanel);
            app.TextAreaLarge.Editable = 'off';
            app.TextAreaLarge.Visible = 'off';
            app.Siguiente4Button = uibutton(app.MainPanel, 'push');
            app.Siguiente4Button.ButtonPushedFcn = createCallbackFcn(app, @Siguiente4ButtonPushed, true);
            app.Siguiente4Button.Visible = 'off';
            app.Siguiente4Button.Text = 'Siguiente 4';
            app.CorrectasEditFieldLabel = uilabel(app.ExamendepracticaPanel);
            app.CorrectasEditFieldLabel.Text = 'Correctas';
            app.CorrectasEditField = uieditfield(app.ExamendepracticaPanel, 'numeric');
            app.ComenzarButton = uibutton(app.ExamendepracticaPanel, 'push');
            app.ComenzarButton.ButtonPushedFcn = createCallbackFcn(app, @ComenzarButtonPushed, true);
            app.ComenzarButton.Text = 'Comenzar';
            app.CalificarButton = uibutton(app.ExamendepracticaPanel, 'push');
            app.CalificarButton.ButtonPushedFcn = createCallbackFcn(app, @CalificarButtonPushed, true);
            app.CalificarButton.Visible = 'off';
            app.CalificarButton.Text = 'Calificar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = preguntas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end