classdef pregunta1_autoreflow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        RightPanel               matlab.ui.container.Panel
        ExamendepracticaPanel    matlab.ui.container.Panel
        MainPanel                matlab.ui.container.ButtonGroup
        Abutton                  matlab.ui.control.RadioButton
        Bbutton                  matlab.ui.control.RadioButton
        Cbutton                  matlab.ui.control.RadioButton
        Dbutton                  matlab.ui.control.RadioButton
        SalirButton              matlab.ui.control.Button
        ImagePentagon            matlab.ui.control.Image
        TextAreaSmall            matlab.ui.control.TextArea
        Siguiente2Button         matlab.ui.control.Button
        Siguiente3Button         matlab.ui.control.Button
        TextAreaLarge            matlab.ui.control.TextArea
        CalificarButton          matlab.ui.control.Button
        CorrectasEditFieldLabel  matlab.ui.control.Label
        CorrectasEditField       matlab.ui.control.NumericEditField
        ComenzarButton           matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Size changed function: ExamendepracticaPanel
        function ExamendepracticaPanelSizeChanged(app, event)
            position = app.ExamendepracticaPanel.Position;
        end

        % Button pushed function: SalirButton
        function SalirButtonPushed(app, event)
            exit
        end

        % Image clicked function: ImagePentagon
        function ImagePentagonClicked(app, event)
            
        end

        % Value changed function: TextAreaSmall
        function TextAreaSmallValueChanged(app, event)
            value = app.TextAreaSmall.Value;
            
        end

        % Button pushed function: Siguiente2Button
        function Siguiente2ButtonPushed(app, event)
            %Escribe la siguiente pregunta VERIFICA SI ES EN TEXTSMALL O
            %LARGE
            app.TextAreaLarge.Value = "Cuanto es 2 + 2?"
            
            %Valores de los incisos para la siguiente pregunta
            app.Abutton.Text = '1';
            app.Bbutton.Text = '3';
            app.Cbutton.Text = 'depende';
            app.Dbutton.Text = '4'; %Correcta
            
            %ocultar y calificar respuesta
            app.Siguiente3Button.Visible = 'on';
            app.Siguiente2Button.Visible = 'off';
            app.MainPanel.Title = 'Pregunta 2';
            app.ImagePentagon.Visible = 'off'
            app.TextAreaSmall.Visible = 'off'
            app.TextAreaLarge.Visible = 'on'
            
            %Recuerda que no estas respondiendo los incisos de aqui sino
            %los de la pregunta anterior
            
            %Respuesta B Pentagono
            if app.Bbutton.Value == 1
                app.CorrectasEditField.Value = app.CorrectasEditField.Value + 1;
            end
        end

        % Button pushed function: Siguiente3Button
        function Siguiente3ButtonPushed(app, event)
            %Escribe la siguiente pregunta VERIFICA SI ES EN TEXTSMALL O
            %LARGE
            app.TextAreaLarge.Value = "Como se llamaba Don pepe?"
            
            %Valores de los incisos para la siguiente pregunta
            app.Abutton.Text = 'Jose';
            app.Bbutton.Text = 'pues pepe, menso'; %Correcta
            app.Cbutton.Text = 'Margarito';
            app.Dbutton.Text = 'Guillermo'; 
            
            %ocultar y calificar respuesta
            %app.Siguiente3Button.Visible = 'off';
            app.CalificarButton.Visible = 'on';
            app.MainPanel.Title = 'Pregunta 3 o Calificacion';
            %app.ImagePentagon.Visible = 'off'
            app.TextAreaSmall.Visible = 'off'
            app.TextAreaLarge.Visible = 'on'
            
            %Recuerda que no estas respondiendo los incisos de aqui sino
            %los de la pregunta anterior
            
            %Respuesta D 4
            if app.Dbutton.Value == 1
                app.CorrectasEditField.Value = app.CorrectasEditField.Value + 1;
            end
        end

        % Button pushed function: CalificarButton
        function CalificarButtonPushed(app, event)
            if app.Abutton.Value == 1
                app.CorrectasEditField.Value = app.CorrectasEditField.Value + 1;
            end
            
            app.MainPanel.Visible = 'off';
            app.ExamendepracticaPanel.Title = 'Examen finalizado con exito!';
            app.CorrectasEditFieldLabel.Visible = 'on';
            app.CorrectasEditField.Visible = 'on';
        end

        % Button pushed function: ComenzarButton
        function ComenzarButtonPushed(app, event)
            app.MainPanel.Visible = 'on';
            app.TextAreaSmall.Value = '¿Cuál es el nombre de la figura?';
            app.Abutton.Text = 'Circulo';
            app.Bbutton.Text = 'Pentagono'; %Correcta
            app.Cbutton.Text = 'Isocosaedro';
            app.Dbutton.Text = 'Cuadrado de 5 lados';
            app.ComenzarButton.Visible = 'off';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.ExamendepracticaPanel = uipanel(app.RightPanel);
            app.ExamendepracticaPanel.Title = 'Examen de practica';
            app.ExamendepracticaPanel.SizeChangedFcn = createCallbackFcn(app, @ExamendepracticaPanelSizeChanged, true);
            app.MainPanel = uibuttongroup(app.ExamendepracticaPanel);
            app.MainPanel.Title = 'Pregunta 1';
            app.MainPanel.Visible = 'off';
            app.Abutton = uiradiobutton(app.MainPanel);
            app.Abutton.Text = '';
            app.Abutton.Value = true;
            app.Bbutton = uiradiobutton(app.MainPanel);
            app.Bbutton.Text = '';
            app.Cbutton = uiradiobutton(app.MainPanel);
            app.Cbutton.Text = '';
            app.Dbutton = uiradiobutton(app.MainPanel);
            app.Dbutton.Text = '';
            app.SalirButton = uibutton(app.MainPanel, 'push');
            app.SalirButton.ButtonPushedFcn = createCallbackFcn(app, @SalirButtonPushed, true);
            app.SalirButton.Text = 'Salir';
            app.ImagePentagon = uiimage(app.MainPanel);
            app.ImagePentagon.ImageClickedFcn = createCallbackFcn(app, @ImagePentagonClicked, true);
            app.ImagePentagon.ImageSource = 'pentagon.png';
            app.TextAreaSmall = uitextarea(app.MainPanel);
            app.TextAreaSmall.ValueChangedFcn = createCallbackFcn(app, @TextAreaSmallValueChanged, true);
            app.Siguiente2Button = uibutton(app.MainPanel, 'push');
            app.Siguiente2Button.ButtonPushedFcn = createCallbackFcn(app, @Siguiente2ButtonPushed, true);
            app.Siguiente2Button.Text = 'Siguiente 2';
            app.Siguiente3Button = uibutton(app.MainPanel, 'push');
            app.Siguiente3Button.ButtonPushedFcn = createCallbackFcn(app, @Siguiente3ButtonPushed, true);
            app.Siguiente3Button.Visible = 'off';
            app.Siguiente3Button.Text = 'Siguiente 3';
            app.TextAreaLarge = uitextarea(app.MainPanel);
            app.TextAreaLarge.Visible = 'off';
            app.CalificarButton = uibutton(app.MainPanel, 'push');
            app.CalificarButton.ButtonPushedFcn = createCallbackFcn(app, @CalificarButtonPushed, true);
            app.CalificarButton.Visible = 'off';
            app.CalificarButton.Text = 'Calificar';
            app.CorrectasEditFieldLabel = uilabel(app.ExamendepracticaPanel);
            app.CorrectasEditFieldLabel.Visible = 'off';
            app.CorrectasEditFieldLabel.Text = 'Correctas';
            app.CorrectasEditField = uieditfield(app.ExamendepracticaPanel, 'numeric');
            app.CorrectasEditField.Visible = 'off';
            app.ComenzarButton = uibutton(app.ExamendepracticaPanel, 'push');
            app.ComenzarButton.ButtonPushedFcn = createCallbackFcn(app, @ComenzarButtonPushed, true);
            app.ComenzarButton.Text = 'Comenzar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pregunta1_autoreflow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end